classdef Robotic_Arm_Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        WhatSimulationwouldyouliketorunLabel  matlab.ui.control.Label
        GazeboButton     matlab.ui.control.Button
        SimulinkButton   matlab.ui.control.Button
        ActualArmButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GazeboButton
        function GazeboButtonPushed(app, event)
            addpath(genpath('~/interbotix_ws/src/Interbotix_src/GUI'));
            gazebo_arm_app;
        end

        % Button pushed function: SimulinkButton
        function SimulinkButtonPushed(app, event)
            addpath(genpath('~/interbotix_ws/src/Interbotix_src/GUI'));
            Simulink_arm_app;
        end

        % Button pushed function: ActualArmButton
        function ActualArmButtonPushed(app, event)
            addpath(genpath('~/interbotix_ws/src/Interbotix_src/GUI'));
            real_arm_app;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.WhatSimulationwouldyouliketorunLabel = uilabel(app.UIFigure);
            app.WhatSimulationwouldyouliketorunLabel.Text = 'What Simulation would you like to run?';
            app.GazeboButton = uibutton(app.UIFigure, 'push');
            app.GazeboButton.ButtonPushedFcn = createCallbackFcn(app, @GazeboButtonPushed, true);
            app.GazeboButton.Text = 'Gazebo';
            app.SimulinkButton = uibutton(app.UIFigure, 'push');
            app.SimulinkButton.ButtonPushedFcn = createCallbackFcn(app, @SimulinkButtonPushed, true);
            app.SimulinkButton.Text = 'Simulink';
            app.ActualArmButton = uibutton(app.UIFigure, 'push');
            app.ActualArmButton.ButtonPushedFcn = createCallbackFcn(app, @ActualArmButtonPushed, true);
            app.ActualArmButton.Text = 'Actual Arm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Robotic_Arm_Main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end