classdef Simulink_arm_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        p_xEditFieldLabel             matlab.ui.control.Label
        p_xEditField                  matlab.ui.control.NumericEditField
        p_yEditFieldLabel             matlab.ui.control.Label
        p_yEditField                  matlab.ui.control.NumericEditField
        p_zEditFieldLabel             matlab.ui.control.Label
        p_zEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel           matlab.ui.control.Label
        alphaEditField                matlab.ui.control.NumericEditField
        z_5xEditFieldLabel            matlab.ui.control.Label
        z_5xEditField                 matlab.ui.control.NumericEditField
        z_5yEditFieldLabel            matlab.ui.control.Label
        z_5yEditField                 matlab.ui.control.NumericEditField
        z_5zEditFieldLabel            matlab.ui.control.Label
        z_5zEditField                 matlab.ui.control.NumericEditField
        SimulationtimeEditFieldLabel  matlab.ui.control.Label
        SimulationtimeEditField       matlab.ui.control.NumericEditField
        pathEditFieldLabel            matlab.ui.control.Label
        pathEditField                 matlab.ui.control.NumericEditField
        LSPB0quintic1Label            matlab.ui.control.Label
        omega_baseEditFieldLabel      matlab.ui.control.Label
        omega_baseEditField           matlab.ui.control.NumericEditField
        omega_shoulderEditFieldLabel  matlab.ui.control.Label
        omega_shoulderEditField       matlab.ui.control.NumericEditField
        omega_elbowEditFieldLabel     matlab.ui.control.Label
        omega_elbowEditField          matlab.ui.control.NumericEditField
        omega_wristEditFieldLabel     matlab.ui.control.Label
        omega_wristEditField          matlab.ui.control.NumericEditField
        omega_gripperEditFieldLabel   matlab.ui.control.Label
        omega_gripperEditField        matlab.ui.control.NumericEditField
        zeta_baseEditFieldLabel       matlab.ui.control.Label
        zeta_baseEditField            matlab.ui.control.NumericEditField
        zeta_wristEditFieldLabel      matlab.ui.control.Label
        zeta_wristEditField           matlab.ui.control.NumericEditField
        zeta_elbowEditFieldLabel      matlab.ui.control.Label
        zeta_elbowEditField           matlab.ui.control.NumericEditField
        zeta_gripperEditFieldLabel    matlab.ui.control.Label
        zeta_gripperEditField         matlab.ui.control.NumericEditField
        zeta_shoulderEditFieldLabel   matlab.ui.control.Label
        zeta_shoulderEditField        matlab.ui.control.NumericEditField
        BasepositionButton            matlab.ui.control.Button
        Ki_baseEditFieldLabel         matlab.ui.control.Label
        Ki_baseEditField              matlab.ui.control.NumericEditField
        Ki_wristEditFieldLabel        matlab.ui.control.Label
        Ki_wristEditField             matlab.ui.control.NumericEditField
        Ki_elbowEditFieldLabel        matlab.ui.control.Label
        Ki_elbowEditField             matlab.ui.control.NumericEditField
        Ki_gripperEditFieldLabel      matlab.ui.control.Label
        Ki_gripperEditField           matlab.ui.control.NumericEditField
        Ki_shoulderEditFieldLabel     matlab.ui.control.Label
        Ki_shoulderEditField          matlab.ui.control.NumericEditField
        r22_baseEditFieldLabel        matlab.ui.control.Label
        r22_baseEditField             matlab.ui.control.NumericEditField
        r22_shoulderEditFieldLabel    matlab.ui.control.Label
        r22_shoulderEditField         matlab.ui.control.NumericEditField
        r22_elbowEditFieldLabel       matlab.ui.control.Label
        r22_elbowEditField            matlab.ui.control.NumericEditField
        r22_wristEditFieldLabel       matlab.ui.control.Label
        r22_wristEditField            matlab.ui.control.NumericEditField
        r22_gripperEditFieldLabel     matlab.ui.control.Label
        r22_gripperEditField          matlab.ui.control.NumericEditField
        r11_baseEditFieldLabel        matlab.ui.control.Label
        r11_baseEditField             matlab.ui.control.NumericEditField
        r11_wristEditFieldLabel       matlab.ui.control.Label
        r11_wristEditField            matlab.ui.control.NumericEditField
        r11_elbowEditFieldLabel       matlab.ui.control.Label
        r11_elbowEditField            matlab.ui.control.NumericEditField
        r11_shoulderEditFieldLabel    matlab.ui.control.Label
        r11_shoulderEditField         matlab.ui.control.NumericEditField
        r11_gripperEditFieldLabel     matlab.ui.control.Label
        r11_gripperEditField          matlab.ui.control.NumericEditField
        ShoulderPositionButton        matlab.ui.control.Button
        ElbowPositionButton           matlab.ui.control.Button
        WristPositionButton           matlab.ui.control.Button
        GripperPositionButton         matlab.ui.control.Button
        BaseTorqueButton              matlab.ui.control.Button
        ShoulderTorqueButton          matlab.ui.control.Button
        ElbowTorqueButton             matlab.ui.control.Button
        WristTorqueButton             matlab.ui.control.Button
        GripperTorqueButton           matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        EndeffectorpositionLabel      matlab.ui.control.Label
        EndeffectoraxisLabel          matlab.ui.control.Label
        GripperrotationLabel          matlab.ui.control.Label
        PlotsLabel                    matlab.ui.control.Label
        r2_baseEditFieldLabel         matlab.ui.control.Label
        r2_baseEditField              matlab.ui.control.NumericEditField
        r2_elbowEditFieldLabel        matlab.ui.control.Label
        r2_elbowEditField             matlab.ui.control.NumericEditField
        r2_wristEditFieldLabel        matlab.ui.control.Label
        r2_wristEditField             matlab.ui.control.NumericEditField
        r2_gripperEditFieldLabel      matlab.ui.control.Label
        r2_gripperEditField           matlab.ui.control.NumericEditField
        r2_shoulderEditFieldLabel     matlab.ui.control.Label
        r2_shoulderEditField          matlab.ui.control.NumericEditField
        SimulinkRoboticarmSimulationLabel  matlab.ui.control.Label
        PositionLabel                 matlab.ui.control.Label
        TorqueLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            addpath(genpath('~/interbotix_ws/src/Interbotix_src/Simulink'));
            p = [app.p_xEditField.Value app.p_yEditField.Value app.p_zEditField.Value 0 0]';
            z_5 = [app.z_5xEditField.Value app.z_5yEditField.Value app.z_5zEditField.Value 0 0]';
            variables = [app.alphaEditField.Value app.SimulationtimeEditField.Value app.pathEditField.Value 0 0]';
            
            omega(1) = app.omega_baseEditField.Value;
            omega(2) = app.omega_shoulderEditField.Value;
            omega(3) = app.omega_elbowEditField.Value;
            omega(4) = app.omega_wristEditField.Value;
            omega(5) = app.omega_gripperEditField.Value;
            omega = omega';
            
            zeta(1)= app.zeta_baseEditField.Value;
            zeta(2) = app.zeta_shoulderEditField.Value;
            zeta(3) = app.zeta_elbowEditField.Value;
            zeta(4) = app.zeta_wristEditField.Value;
            zeta(5) = app.zeta_gripperEditField.Value;
            zeta = zeta';
            
            Ki(1) = app.Ki_baseEditField.Value;
            Ki(2) = app.Ki_shoulderEditField.Value;
            Ki(3) = app.Ki_elbowEditField.Value;
            Ki(4) = app.Ki_wristEditField.Value;
            Ki(5) = app.Ki_gripperEditField.Value;
            Ki = Ki';
            
            r11(1) = app.r11_baseEditField.Value;
            r11(2) = app.r11_shoulderEditField.Value;
            r11(3) = app.r11_elbowEditField.Value;
            r11(4) = app.r11_wristEditField.Value;
            r11(5) = app.r11_gripperEditField.Value;
            r11 = r11';
            
            r22(1) = app.r22_baseEditField.Value;
            r22(2) = app.r22_shoulderEditField.Value;
            r22(3) = app.r22_elbowEditField.Value;
            r22(4) = app.r22_wristEditField.Value;
            r22(5) = app.r22_gripperEditField.Value;
            r22 = r22';
            
            r2(1) = app.r2_baseEditField.Value;
            r2(2) = app.r2_shoulderEditField.Value;
            r2(3) = app.r2_elbowEditField.Value;
            r2(4) = app.r2_wristEditField.Value;
            r2(5) = app.r2_gripperEditField.Value;
            r2 = r2';
            
            
            simu_data = table(p, z_5, variables, omega, zeta, Ki,r11, r22, r2);
            filename = '~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_arm_data.xlsx';
            writetable(simu_data,filename);
            
            pause(1)
            Simulink_app_code_main;
                
            
        end

        % Button pushed function: BasepositionButton
        function BasepositionButtonPushed(app, event)
            q_ref = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/q_ref.xlsx');
            sim_out_tout = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_tout.xlsx');
            sim_out_data = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_q_base.xlsx');
            
            set(figure,'Color','white')
            plot(sim_out_data(:,1),sim_out_data(:,2),'b-',sim_out_tout,q_ref(:,1),'k-.','LineWidth',2)
            l= legend('$$q_1$$', '$$q_{1,{\rm ref}}$$');
            set(l,'interpreter','latex','fontsize',20);
            set(gca,'fontsize',15)
            xlabel('$$t \, {\rm [s]}$$','interpreter','latex','fontsize',20)
            axis tight
            title('Base Position')
        end

        % Button pushed function: ShoulderPositionButton
        function ShoulderPositionButtonPushed(app, event)
            q_ref = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/q_ref.xlsx');
            sim_out_tout = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_tout.xlsx');
            sim_out_data = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_q_shoulder.xlsx');
            
            set(figure,'Color','white')
            plot(sim_out_data(:,1),sim_out_data(:,2),'b-',sim_out_tout,q_ref(:,2),'k-.','LineWidth',2)
            l= legend('$$q_2$$', '$$q_{2,{\rm ref}}$$');
            set(l,'interpreter','latex','fontsize',20);
            set(gca,'fontsize',15)
            xlabel('$$t \, {\rm [s]}$$','interpreter','latex','fontsize',20)
            axis tight
            title('Shoulder Position')
        end

        % Button pushed function: ElbowPositionButton
        function ElbowPositionButtonPushed(app, event)
            q_ref = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/q_ref.xlsx');
            sim_out_tout = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_tout.xlsx');
            sim_out_data = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_q_elbow.xlsx');
            
            set(figure,'Color','white')
            plot(sim_out_data(:,1),sim_out_data(:,2),'b-',sim_out_tout,q_ref(:,3),'k-.','LineWidth',2)
            l= legend('$$q_3$$', '$$q_{3,{\rm ref}}$$');
            set(l,'interpreter','latex','fontsize',20);
            set(gca,'fontsize',15)
            xlabel('$$t \, {\rm [s]}$$','interpreter','latex','fontsize',20)
            axis tight
            title('Elbow Position')
            
        end

        % Button pushed function: WristPositionButton
        function WristPositionButtonPushed(app, event)
            q_ref = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/q_ref.xlsx');
            sim_out_tout = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_tout.xlsx');
            sim_out_data = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_q_wrist.xlsx');
            
            set(figure,'Color','white')
            plot(sim_out_data(:,1),sim_out_data(:,2),'b-',sim_out_tout,q_ref(:,4),'k-.','LineWidth',2)
            l= legend('$$q_4$$', '$$q_{4,{\rm ref}}$$');
            set(l,'interpreter','latex','fontsize',20);
            set(gca,'fontsize',15)
            xlabel('$$t \, {\rm [s]}$$','interpreter','latex','fontsize',20)
            axis tight
            title('Wrist Position')
        end

        % Button pushed function: GripperPositionButton
        function GripperPositionButtonPushed(app, event)
            q_ref = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/q_ref.xlsx');
            sim_out_tout = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_tout.xlsx');
            sim_out_data = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_q_gripper.xlsx');
            
            set(figure,'Color','white')
            plot(sim_out_data(:,1),sim_out_data(:,2),'b-',sim_out_tout,q_ref(:,5),'k-.','LineWidth',2)
            l= legend('$$q_5$$', '$$q_{5,{\rm ref}}$$');
            set(l,'interpreter','latex','fontsize',20);
            set(gca,'fontsize',15)
            xlabel('$$t \, {\rm [s]}$$','interpreter','latex','fontsize',20)
            axis tight
            title('Gripper Position')
        end

        % Button pushed function: BaseTorqueButton
        function BaseTorqueButtonPushed(app, event)
            %base torque
            sim_out_data = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_u_base.xlsx');
            
            set(figure,'Color','white')
            plot(sim_out_data(:,1), sim_out_data(:,2),'b-','LineWidth',2)
            l= legend('$$u_{base}$$');
            set(l,'interpreter','latex','fontsize',20);
            set(gca,'fontsize',15)
            xlabel('$$t \, {\rm [s]}$$','interpreter','latex','fontsize',20)
            axis tight
            title('Base Torque')
        end

        % Button pushed function: ShoulderTorqueButton
        function ShoulderTorqueButtonPushed(app, event)
            sim_out_data = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_u_shoulder.xlsx');
            
            set(figure,'Color','white')
            plot(sim_out_data(:,1), sim_out_data(:,2),'b-','LineWidth',2)
            l= legend('$$u_{shoulder}$$');
            set(l,'interpreter','latex','fontsize',20);
            set(gca,'fontsize',15)
            xlabel('$$t \, {\rm [s]}$$','interpreter','latex','fontsize',20)
            axis tight
            title('Shoulder Torque')
        end

        % Button pushed function: ElbowTorqueButton
        function ElbowTorqueButtonPushed(app, event)
            sim_out_data = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_u_elbow.xlsx');
            
            set(figure,'Color','white')
            plot(sim_out_data(:,1), sim_out_data(:,2),'b-','LineWidth',2)
            l= legend('$$u_{elbow}$$');
            set(l,'interpreter','latex','fontsize',20);
            set(gca,'fontsize',15)
            xlabel('$$t \, {\rm [s]}$$','interpreter','latex','fontsize',20)
            axis tight
            title('Elbow Torque')
        end

        % Button pushed function: WristTorqueButton
        function WristTorqueButtonPushed(app, event)
            sim_out_data = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_u_wrist.xlsx');
            
            set(figure,'Color','white')
            plot(sim_out_data(:,1), sim_out_data(:,2),'b-','LineWidth',2)
            l= legend('$$u_{wrist}$$');
            set(l,'interpreter','latex','fontsize',20);
            set(gca,'fontsize',15)
            xlabel('$$t \, {\rm [s]}$$','interpreter','latex','fontsize',20)
            axis tight
            title('Wrist Torque')
            
        end

        % Button pushed function: GripperTorqueButton
        function GripperTorqueButtonPushed(app, event)
            sim_out_data = xlsread('~/interbotix_ws/src/Interbotix_src/Simulink/simulink_app_data/sim_out_u_gripper.xlsx');
            
            set(figure,'Color','white')
            plot(sim_out_data(:,1), sim_out_data(:,2),'b-','LineWidth',2)
            l= legend('$$u_{gripper}$$');
            set(l,'interpreter','latex','fontsize',20);
            set(gca,'fontsize',15)
            xlabel('$$t \, {\rm [s]}$$','interpreter','latex','fontsize',20)
            axis tight
            title('Gripper Torque')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.p_xEditFieldLabel = uilabel(app.UIFigure);
            app.p_xEditFieldLabel.Text = 'p_x';
            app.p_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_xEditField.Value = -0.3;
            app.p_yEditFieldLabel = uilabel(app.UIFigure);
            app.p_yEditFieldLabel.Text = 'p_y';
            app.p_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_yEditField.Value = 0.1;
            app.p_zEditFieldLabel = uilabel(app.UIFigure);
            app.p_zEditFieldLabel.Text = 'p_z';
            app.p_zEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_zEditField.Value = 0.23;
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_5xEditFieldLabel = uilabel(app.UIFigure);
            app.z_5xEditFieldLabel.Text = 'z_5x';
            app.z_5xEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_5yEditFieldLabel = uilabel(app.UIFigure);
            app.z_5yEditFieldLabel.Text = 'z_5y';
            app.z_5yEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_5yEditField.Value = 1;
            app.z_5zEditFieldLabel = uilabel(app.UIFigure);
            app.z_5zEditFieldLabel.Text = 'z_5z';
            app.z_5zEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_5zEditField.Value = 0.2;
            app.SimulationtimeEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationtimeEditFieldLabel.Text = 'Simulation time';
            app.SimulationtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationtimeEditField.Value = 10;
            app.pathEditFieldLabel = uilabel(app.UIFigure);
            app.pathEditFieldLabel.Text = 'path';
            app.pathEditField = uieditfield(app.UIFigure, 'numeric');
            app.LSPB0quintic1Label = uilabel(app.UIFigure);
            app.LSPB0quintic1Label.Text = '{LSPB(0) / quintic(1)}';
            app.omega_baseEditFieldLabel = uilabel(app.UIFigure);
            app.omega_baseEditFieldLabel.Text = 'omega_base';
            app.omega_baseEditField = uieditfield(app.UIFigure, 'numeric');
            app.omega_baseEditField.Value = 2;
            app.omega_shoulderEditFieldLabel = uilabel(app.UIFigure);
            app.omega_shoulderEditFieldLabel.Text = 'omega_shoulder';
            app.omega_shoulderEditField = uieditfield(app.UIFigure, 'numeric');
            app.omega_shoulderEditField.Value = 4;
            app.omega_elbowEditFieldLabel = uilabel(app.UIFigure);
            app.omega_elbowEditFieldLabel.Text = 'omega_elbow';
            app.omega_elbowEditField = uieditfield(app.UIFigure, 'numeric');
            app.omega_elbowEditField.Value = 2.5;
            app.omega_wristEditFieldLabel = uilabel(app.UIFigure);
            app.omega_wristEditFieldLabel.Text = 'omega_wrist';
            app.omega_wristEditField = uieditfield(app.UIFigure, 'numeric');
            app.omega_wristEditField.Value = 1.5;
            app.omega_gripperEditFieldLabel = uilabel(app.UIFigure);
            app.omega_gripperEditFieldLabel.Text = 'omega_gripper';
            app.omega_gripperEditField = uieditfield(app.UIFigure, 'numeric');
            app.omega_gripperEditField.Value = 3;
            app.zeta_baseEditFieldLabel = uilabel(app.UIFigure);
            app.zeta_baseEditFieldLabel.Text = 'zeta_base';
            app.zeta_baseEditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta_baseEditField.Value = 0.1;
            app.zeta_wristEditFieldLabel = uilabel(app.UIFigure);
            app.zeta_wristEditFieldLabel.Text = 'zeta_wrist';
            app.zeta_wristEditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta_wristEditField.Value = 0.7;
            app.zeta_elbowEditFieldLabel = uilabel(app.UIFigure);
            app.zeta_elbowEditFieldLabel.Text = 'zeta_elbow';
            app.zeta_elbowEditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta_elbowEditField.Value = 2;
            app.zeta_gripperEditFieldLabel = uilabel(app.UIFigure);
            app.zeta_gripperEditFieldLabel.Text = 'zeta_gripper';
            app.zeta_gripperEditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta_gripperEditField.Value = 0.7;
            app.zeta_shoulderEditFieldLabel = uilabel(app.UIFigure);
            app.zeta_shoulderEditFieldLabel.Text = 'zeta_shoulder';
            app.zeta_shoulderEditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta_shoulderEditField.Value = 5;
            app.BasepositionButton = uibutton(app.UIFigure, 'push');
            app.BasepositionButton.ButtonPushedFcn = createCallbackFcn(app, @BasepositionButtonPushed, true);
            app.BasepositionButton.Text = 'Base position';
            app.Ki_baseEditFieldLabel = uilabel(app.UIFigure);
            app.Ki_baseEditFieldLabel.Text = 'Ki_base';
            app.Ki_baseEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ki_baseEditField.Value = 0.5;
            app.Ki_wristEditFieldLabel = uilabel(app.UIFigure);
            app.Ki_wristEditFieldLabel.Text = 'Ki_wrist';
            app.Ki_wristEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ki_wristEditField.Value = 10;
            app.Ki_elbowEditFieldLabel = uilabel(app.UIFigure);
            app.Ki_elbowEditFieldLabel.Text = 'Ki_elbow';
            app.Ki_elbowEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ki_elbowEditField.Value = 40;
            app.Ki_gripperEditFieldLabel = uilabel(app.UIFigure);
            app.Ki_gripperEditFieldLabel.Text = 'Ki_gripper';
            app.Ki_gripperEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ki_gripperEditField.Value = 0.7;
            app.Ki_shoulderEditFieldLabel = uilabel(app.UIFigure);
            app.Ki_shoulderEditFieldLabel.Text = 'Ki_shoulder';
            app.Ki_shoulderEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ki_shoulderEditField.Value = 20;
            app.r22_baseEditFieldLabel = uilabel(app.UIFigure);
            app.r22_baseEditFieldLabel.Text = 'r22_base';
            app.r22_baseEditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_baseEditField.Value = 100;
            app.r22_shoulderEditFieldLabel = uilabel(app.UIFigure);
            app.r22_shoulderEditFieldLabel.Text = 'r22_shoulder';
            app.r22_shoulderEditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_shoulderEditField.Value = 100;
            app.r22_elbowEditFieldLabel = uilabel(app.UIFigure);
            app.r22_elbowEditFieldLabel.Text = 'r22_elbow';
            app.r22_elbowEditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_elbowEditField.Value = 100;
            app.r22_wristEditFieldLabel = uilabel(app.UIFigure);
            app.r22_wristEditFieldLabel.Text = 'r22_wrist';
            app.r22_wristEditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_wristEditField.Value = 1000;
            app.r22_gripperEditFieldLabel = uilabel(app.UIFigure);
            app.r22_gripperEditFieldLabel.Text = 'r22_gripper';
            app.r22_gripperEditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_gripperEditField.Value = 1000;
            app.r11_baseEditFieldLabel = uilabel(app.UIFigure);
            app.r11_baseEditFieldLabel.Text = 'r11_base';
            app.r11_baseEditField = uieditfield(app.UIFigure, 'numeric');
            app.r11_baseEditField.Value = 10000;
            app.r11_wristEditFieldLabel = uilabel(app.UIFigure);
            app.r11_wristEditFieldLabel.Text = 'r11_wrist';
            app.r11_wristEditField = uieditfield(app.UIFigure, 'numeric');
            app.r11_wristEditField.Value = 50000;
            app.r11_elbowEditFieldLabel = uilabel(app.UIFigure);
            app.r11_elbowEditFieldLabel.Text = 'r11_elbow';
            app.r11_elbowEditField = uieditfield(app.UIFigure, 'numeric');
            app.r11_elbowEditField.Value = 100000;
            app.r11_shoulderEditFieldLabel = uilabel(app.UIFigure);
            app.r11_shoulderEditFieldLabel.Text = 'r11_shoulder';
            app.r11_shoulderEditField = uieditfield(app.UIFigure, 'numeric');
            app.r11_shoulderEditField.Value = 100000;
            app.r11_gripperEditFieldLabel = uilabel(app.UIFigure);
            app.r11_gripperEditFieldLabel.Text = 'r11_gripper';
            app.r11_gripperEditField = uieditfield(app.UIFigure, 'numeric');
            app.r11_gripperEditField.Value = 10000;
            app.ShoulderPositionButton = uibutton(app.UIFigure, 'push');
            app.ShoulderPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ShoulderPositionButtonPushed, true);
            app.ShoulderPositionButton.Text = 'Shoulder Position';
            app.ElbowPositionButton = uibutton(app.UIFigure, 'push');
            app.ElbowPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ElbowPositionButtonPushed, true);
            app.ElbowPositionButton.Text = 'Elbow Position';
            app.WristPositionButton = uibutton(app.UIFigure, 'push');
            app.WristPositionButton.ButtonPushedFcn = createCallbackFcn(app, @WristPositionButtonPushed, true);
            app.WristPositionButton.Text = 'Wrist Position';
            app.GripperPositionButton = uibutton(app.UIFigure, 'push');
            app.GripperPositionButton.ButtonPushedFcn = createCallbackFcn(app, @GripperPositionButtonPushed, true);
            app.GripperPositionButton.Text = 'Gripper Position';
            app.BaseTorqueButton = uibutton(app.UIFigure, 'push');
            app.BaseTorqueButton.ButtonPushedFcn = createCallbackFcn(app, @BaseTorqueButtonPushed, true);
            app.BaseTorqueButton.Text = 'Base Torque';
            app.ShoulderTorqueButton = uibutton(app.UIFigure, 'push');
            app.ShoulderTorqueButton.ButtonPushedFcn = createCallbackFcn(app, @ShoulderTorqueButtonPushed, true);
            app.ShoulderTorqueButton.Text = 'Shoulder Torque';
            app.ElbowTorqueButton = uibutton(app.UIFigure, 'push');
            app.ElbowTorqueButton.ButtonPushedFcn = createCallbackFcn(app, @ElbowTorqueButtonPushed, true);
            app.ElbowTorqueButton.Text = 'Elbow Torque';
            app.WristTorqueButton = uibutton(app.UIFigure, 'push');
            app.WristTorqueButton.ButtonPushedFcn = createCallbackFcn(app, @WristTorqueButtonPushed, true);
            app.WristTorqueButton.Text = 'Wrist Torque';
            app.GripperTorqueButton = uibutton(app.UIFigure, 'push');
            app.GripperTorqueButton.ButtonPushedFcn = createCallbackFcn(app, @GripperTorqueButtonPushed, true);
            app.GripperTorqueButton.Text = 'Gripper Torque';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.EndeffectorpositionLabel = uilabel(app.UIFigure);
            app.EndeffectorpositionLabel.Text = 'End effector position:';
            app.EndeffectoraxisLabel = uilabel(app.UIFigure);
            app.EndeffectoraxisLabel.Text = 'End effector axis:';
            app.GripperrotationLabel = uilabel(app.UIFigure);
            app.GripperrotationLabel.Text = '(Gripper rotation)';
            app.PlotsLabel = uilabel(app.UIFigure);
            app.PlotsLabel.Text = 'Plots:';
            app.r2_baseEditFieldLabel = uilabel(app.UIFigure);
            app.r2_baseEditFieldLabel.Text = 'r2_base';
            app.r2_baseEditField = uieditfield(app.UIFigure, 'numeric');
            app.r2_baseEditField.Value = 1;
            app.r2_elbowEditFieldLabel = uilabel(app.UIFigure);
            app.r2_elbowEditFieldLabel.Text = 'r2_elbow';
            app.r2_elbowEditField = uieditfield(app.UIFigure, 'numeric');
            app.r2_elbowEditField.Value = 0.01;
            app.r2_wristEditFieldLabel = uilabel(app.UIFigure);
            app.r2_wristEditFieldLabel.Text = 'r2_wrist';
            app.r2_wristEditField = uieditfield(app.UIFigure, 'numeric');
            app.r2_wristEditField.Value = 1;
            app.r2_gripperEditFieldLabel = uilabel(app.UIFigure);
            app.r2_gripperEditFieldLabel.Text = 'r2_gripper';
            app.r2_gripperEditField = uieditfield(app.UIFigure, 'numeric');
            app.r2_gripperEditField.Value = 1;
            app.r2_shoulderEditFieldLabel = uilabel(app.UIFigure);
            app.r2_shoulderEditFieldLabel.Text = 'r2_shoulder';
            app.r2_shoulderEditField = uieditfield(app.UIFigure, 'numeric');
            app.r2_shoulderEditField.Value = 1e-05;
            app.SimulinkRoboticarmSimulationLabel = uilabel(app.UIFigure);
            app.SimulinkRoboticarmSimulationLabel.Text = 'Simulink Robotic arm Simulation';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.TorqueLabel = uilabel(app.UIFigure);
            app.TorqueLabel.Text = 'Torque';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulink_arm_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end