classdef gazebo_arm_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        omega1EditFieldLabel         matlab.ui.control.Label
        omega1EditField              matlab.ui.control.NumericEditField
        omega2EditFieldLabel         matlab.ui.control.Label
        omega2EditField              matlab.ui.control.NumericEditField
        omega3EditFieldLabel         matlab.ui.control.Label
        omega3EditField              matlab.ui.control.NumericEditField
        omega4EditFieldLabel         matlab.ui.control.Label
        omega4EditField              matlab.ui.control.NumericEditField
        omega5EditFieldLabel         matlab.ui.control.Label
        omega5EditField              matlab.ui.control.NumericEditField
        r2link1EditFieldLabel        matlab.ui.control.Label
        r2link1EditField             matlab.ui.control.NumericEditField
        r2link2EditFieldLabel        matlab.ui.control.Label
        r2link2EditField             matlab.ui.control.NumericEditField
        r2link3EditFieldLabel        matlab.ui.control.Label
        r2link3EditField             matlab.ui.control.NumericEditField
        r2link4EditFieldLabel        matlab.ui.control.Label
        r2link4EditField             matlab.ui.control.NumericEditField
        r2link5EditFieldLabel        matlab.ui.control.Label
        r2link5EditField             matlab.ui.control.NumericEditField
        r22link1EditFieldLabel       matlab.ui.control.Label
        r22link1EditField            matlab.ui.control.NumericEditField
        r22link2EditFieldLabel       matlab.ui.control.Label
        r22link2EditField            matlab.ui.control.NumericEditField
        r22link3EditFieldLabel       matlab.ui.control.Label
        r22link3EditField            matlab.ui.control.NumericEditField
        r22link4EditFieldLabel       matlab.ui.control.Label
        r22link4EditField            matlab.ui.control.NumericEditField
        r22link5EditFieldLabel       matlab.ui.control.Label
        r22link5EditField            matlab.ui.control.NumericEditField
        r11link1EditFieldLabel       matlab.ui.control.Label
        r11link1EditField            matlab.ui.control.NumericEditField
        r11link2EditFieldLabel       matlab.ui.control.Label
        r11link2EditField            matlab.ui.control.NumericEditField
        r11link3EditFieldLabel       matlab.ui.control.Label
        r11link3EditField            matlab.ui.control.NumericEditField
        r11link4EditFieldLabel       matlab.ui.control.Label
        r11link4EditField            matlab.ui.control.NumericEditField
        r11link5EditFieldLabel       matlab.ui.control.Label
        r11link5EditField            matlab.ui.control.NumericEditField
        zeta1EditFieldLabel          matlab.ui.control.Label
        zeta1EditField               matlab.ui.control.NumericEditField
        zeta2EditFieldLabel          matlab.ui.control.Label
        zeta2EditField               matlab.ui.control.NumericEditField
        zeta3EditFieldLabel          matlab.ui.control.Label
        zeta3EditField               matlab.ui.control.NumericEditField
        zeta4EditFieldLabel          matlab.ui.control.Label
        zeta4EditField               matlab.ui.control.NumericEditField
        zeta5EditFieldLabel          matlab.ui.control.Label
        zeta5EditField               matlab.ui.control.NumericEditField
        T_maxEditFieldLabel          matlab.ui.control.Label
        T_maxEditField               matlab.ui.control.NumericEditField
        alphaEditFieldLabel          matlab.ui.control.Label
        alphaEditField               matlab.ui.control.NumericEditField
        TrajectoryplanningEditFieldLabel  matlab.ui.control.Label
        TrajectoryplanningEditField  matlab.ui.control.NumericEditField
        z_5xEditFieldLabel           matlab.ui.control.Label
        z_5xEditField                matlab.ui.control.NumericEditField
        z_5yEditFieldLabel           matlab.ui.control.Label
        z_5yEditField                matlab.ui.control.NumericEditField
        z_5zEditFieldLabel           matlab.ui.control.Label
        z_5zEditField                matlab.ui.control.NumericEditField
        p_xEditFieldLabel            matlab.ui.control.Label
        p_xEditField                 matlab.ui.control.NumericEditField
        p_yEditFieldLabel            matlab.ui.control.Label
        p_yEditField                 matlab.ui.control.NumericEditField
        p_zEditFieldLabel            matlab.ui.control.Label
        p_zEditField                 matlab.ui.control.NumericEditField
        UpdateParametersButton       matlab.ui.control.Button
        connect                      matlab.ui.control.Button
        GraphsLabel                  matlab.ui.control.Label
        WaistPositionButton          matlab.ui.control.Button
        ElbowPositionButton          matlab.ui.control.Button
        WristRotateButton            matlab.ui.control.Button
        ShoulderPositionButton       matlab.ui.control.Button
        WristPositionButton          matlab.ui.control.Button
        GazeboRoboticArmSimulationLabel  matlab.ui.control.Label
        LSPB1quinticLabel            matlab.ui.control.Label
        GripperRotationLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        connect_data = 0; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
                        
            T_max = app.T_maxEditField;          

            % Define the initial position and the initial velocity         
            omega_link1 = app.omega1EditField.Value;
            omega_link2 = app.omega2EditField.Value;
            omega_link3 = app.omega3EditField.Value;
            omega_link4 = app.omega4EditField.Value;
            omega_link5 = app.omega5EditField.Value; 
            zeta_link1 = app.zeta1EditField.Value;
            zeta_link2 = app.zeta2EditField.Value;
            zeta_link3 = app.zeta3EditField.Value;
            zeta_link4 = app.zeta4EditField.Value;
            zeta_link5 = app.zeta5EditField.Value;
            
            zeta = [zeta_link1,zeta_link2,zeta_link3,zeta_link4,zeta_link5]';
            omega = [omega_link1,omega_link2,omega_link3,omega_link4,omega_link5]';
            
            % Parameters for the LQR controller
            r11_link1 = app.r11link1EditField.Value;           % Must be positive
            r22_link1 = app.r22link1EditField.Value;           % Must be positive
            R1_link1 = [r11_link1,0;0,r22_link1];

            r11_link2 = app.r11link2EditField.Value;           % Must be positive
            r22_link2 = app.r22link2EditField.Value;           % Must be positive
            R1_link2 = [r11_link2,0;0,r22_link2];
 
            r11_link3 = app.r11link3EditField.Value;           % Must be positive
            r22_link3 = app.r22link3EditField.Value;           % Must be positive
            R1_link3 = [r11_link3,0;0,r22_link3];
            
            r11_link4 = app.r11link4EditField.Value;           % Must be positive
            r22_link4 = app.r22link4EditField.Value;           % Must be positive
            R1_link4 = [r11_link4,0;0,r22_link4];
            
            r11_link5 = app.r11link5EditField.Value;           % Must be positive
            r22_link5 = app.r22link5EditField.Value;           % Must be positive
            R1_link5 = [r11_link5,0;0,r22_link5];
            
            r2_link1 = app.r2link1EditField.Value;           % Must be positive
            r2_link2 = app.r2link2EditField.Value;           % Must be positive
            r2_link3 = app.r2link3EditField.Value;
            r2_link4 = app.r2link4EditField.Value;
            r2_link5 = app.r2link5EditField.Value;
            
            alpha = app.alphaEditField.Value;
            trajectory_planning = app.TrajectoryplanningEditField.Value;
            
            A1 = [0,1;-2*zeta_link1*omega_link1,-omega_link1^2]; % A matrix for link 1
            A2 = [0,1;-2*zeta_link2*omega_link2,-omega_link2^2]; % A matrix for link 2
            A3 = [0,1;-2*zeta_link3*omega_link3,-omega_link3^2]; % A matrix for link 3
            A4 = [0,1;-2*zeta_link4*omega_link4,-omega_link4^2]; % A matrix for link 4
            A5 = [0,1;-2*zeta_link5*omega_link5,-omega_link5^2]; % A matrix for link 5
                        

            % Do not modify B, C, D
            B = [0;1];
            C = eye(2);
            D = [0;0];
            
            sys1 = ss(A1,B,C,D); % Matlab representation of the first link's dynamics
            sys2 = ss(A2,B,C,D); % Matlab representation of the second link's dynamics
            sys3 = ss(A3,B,C,D); % Matlab representation of the third link's dynamics
            sys4 = ss(A4,B,C,D); % Matlab representation of the fourth link's dynamics
            sys5 = ss(A5,B,C,D); % Matlab representation of the fifth link's dynamics
            
            [K_LQR1,~,~] = lqr(sys1,R1_link1,r2_link1,zeros(2,1));
            [K_LQR2,~,~] = lqr(sys2,R1_link2,r2_link2,zeros(2,1));
            [K_LQR3,~,~] = lqr(sys3,R1_link3,r2_link3,zeros(2,1));
            [K_LQR4,~,~] = lqr(sys4,R1_link4,r2_link4,zeros(2,1));
            [K_LQR5,~,~] = lqr(sys5,R1_link5,r2_link5,zeros(2,1));

            fileID = fopen('~/interbotix_ws/src/Interbotix_src/Gazebo/Data_log/data_to_read.txt','w');          
            
            fprintf(fileID, 'omega_link1 = %d \n', omega_link1);
            fprintf(fileID, 'omega_link2 = %d \n', omega_link2);
            fprintf(fileID, 'omega_link3 = %d \n', omega_link3);
            fprintf(fileID, 'omega_link4 = %d \n', omega_link4);
            fprintf(fileID, 'omega_link5 = %d \n', omega_link5);
            
            fprintf(fileID, 'zeta_link1 = %d \n', zeta_link1);
            fprintf(fileID, 'zeta_link2 = %d \n', zeta_link2);
            fprintf(fileID, 'zeta_link3 = %d \n', zeta_link3);
            fprintf(fileID, 'zeta_link4 = %d \n', zeta_link4);
            fprintf(fileID, 'zeta_link5 = %d \n', zeta_link5);
         
            fprintf(fileID, 'K_LQR1_0 = %d \n', K_LQR1(1));
            fprintf(fileID, 'K_LQR1_1 = %d \n', K_LQR1(2));
            
            fprintf(fileID, 'K_LQR2_0 = %d \n', K_LQR2(1));
            fprintf(fileID, 'K_LQR2_1 = %d \n', K_LQR2(2));
            
            fprintf(fileID, 'K_LQR3_0 = %d \n', K_LQR3(1));
            fprintf(fileID, 'K_LQR3_1 = %d \n', K_LQR3(2));
            
            fprintf(fileID, 'K_LQR4_0 = %d \n', K_LQR4(1));
            fprintf(fileID, 'K_LQR4_1 = %d \n', K_LQR4(2));
            
            fprintf(fileID, 'K_LQR5_0 = %d \n', K_LQR5(1));
            fprintf(fileID, 'K_LQR5_1 = %d \n', K_LQR5(2));
            
            fprintf(fileID, 'alpha = %d \n', app.alphaEditField.Value);
            
            fprintf(fileID, 'p_x = %d \n', app.p_xEditField.Value);
            fprintf(fileID, 'p_y = %d \n', app.p_yEditField.Value);
            fprintf(fileID, 'p_z = %d \n', app.p_zEditField.Value);
            
            fprintf(fileID, 'z_5x = %d \n', app.z_5xEditField.Value);
            fprintf(fileID, 'z_5y = %d \n', app.z_5yEditField.Value);
            fprintf(fileID, 'z_5z = %d \n', app.z_5zEditField.Value);
            
            fprintf(fileID, 'trajectory planning = %d \n', app.TrajectoryplanningEditField.Value);
            fprintf(fileID, 'T_max = %d \n', app.T_maxEditField.Value);
            
            fclose(fileID);
                 
            
        end

        % Button pushed function: connect
        function ConnectButtonPushed(app, event)
            addpath(genpath('~/interbotix_ws'));
            app.connect_data = ~app.connect_data;
            
            if app.connect_data
                app.connect.Text = 'Disconnect' ;
            
                setenv('LD_LIBRARY_PATH', '/usr/lib/x86_64-linux-gnu:/opt/ros/melodic/lib/');
                addpath(genpath('~/interbotix_ws'));
                status = system('roslaunch interbotix_gazebo gazebo_matlab.launch &');            
                T_max = app.T_maxEditField;          
                                   
                % Define the initial position and the initial velocity
                
                omega_link1 = app.omega1EditField.Value;
                omega_link2 = app.omega2EditField.Value;
                omega_link3 = app.omega3EditField.Value;
                omega_link4 = app.omega4EditField.Value;
                omega_link5 = app.omega5EditField.Value; 
                zeta_link1 = app.zeta1EditField.Value;
                zeta_link2 = app.zeta2EditField.Value;
                zeta_link3 = app.zeta3EditField.Value;
                zeta_link4 = app.zeta4EditField.Value;
                zeta_link5 = app.zeta5EditField.Value;
                
                zeta = [zeta_link1,zeta_link2,zeta_link3,zeta_link4,zeta_link5]';
                omega = [omega_link1,omega_link2,omega_link3,omega_link4,omega_link5]';
                
                % Parameters for the LQR controller
                r11_link1 = app.r11link1EditField.Value;           % Must be positive
                r22_link1 = app.r22link1EditField.Value;           % Must be positive
                R1_link1 = [r11_link1,0;0,r22_link1];
                
                r11_link2 = app.r11link2EditField.Value;           % Must be positive
                r22_link2 = app.r22link2EditField.Value;           % Must be positive
                R1_link2 = [r11_link2,0;0,r22_link2];
                
                r11_link3 = app.r11link3EditField.Value;           % Must be positive
                r22_link3 = app.r22link3EditField.Value;           % Must be positive
                R1_link3 = [r11_link3,0;0,r22_link3];
                
                r11_link4 = app.r11link4EditField.Value;           % Must be positive
                r22_link4 = app.r22link4EditField.Value;           % Must be positive
                R1_link4 = [r11_link4,0;0,r22_link4];
                
                r11_link5 = app.r11link5EditField.Value;           % Must be positive
                r22_link5 = app.r22link5EditField.Value;           % Must be positive
                R1_link5 = [r11_link5,0;0,r22_link5];
                
                r2_link1 = app.r2link1EditField.Value;           % Must be positive
                r2_link2 = app.r2link2EditField.Value;           % Must be positive
                r2_link3 = app.r2link3EditField.Value;
                r2_link4 = app.r2link4EditField.Value;
                r2_link5 = app.r2link5EditField.Value;
                
    %             
                A1 = [0,1;-2*zeta_link1*omega_link1,-omega_link1^2]; % A matrix for link 1
                A2 = [0,1;-2*zeta_link2*omega_link2,-omega_link2^2]; % A matrix for link 2
                A3 = [0,1;-2*zeta_link3*omega_link3,-omega_link3^2]; % A matrix for link 3
                A4 = [0,1;-2*zeta_link4*omega_link4,-omega_link4^2]; % A matrix for link 4
                A5 = [0,1;-2*zeta_link5*omega_link5,-omega_link5^2]; % A matrix for link 5
                               
    
               % Do not modify B, C, D
                B = [0;1];
                C = eye(2);
                D = [0;0];
                
                sys1 = ss(A1,B,C,D); % Matlab representation of the first link's dynamics
                sys2 = ss(A2,B,C,D); % Matlab representation of the second link's dynamics
                sys3= ss(A3,B,C,D); % Matlab representation of the third link's dynamics
                sys4 = ss(A4,B,C,D); % Matlab representation of the fourth link's dynamics
                sys5 = ss(A5,B,C,D); % Matlab representation of the fifth link's dynamics
                
                [K_LQR1,~,~] = lqr(sys1,R1_link1,r2_link1,zeros(2,1));
                [K_LQR2,~,~] = lqr(sys2,R1_link2,r2_link2,zeros(2,1));
                [K_LQR3,~,~] = lqr(sys3,R1_link3,r2_link3,zeros(2,1));
                [K_LQR4,~,~] = lqr(sys4,R1_link4,r2_link4,zeros(2,1));
                [K_LQR5,~,~] = lqr(sys5,R1_link5,r2_link5,zeros(2,1));
                
                fileID = fopen('~/interbotix_ws/src/Interbotix_src/Gazebo/Data_log/data_to_read.txt','w');   
%                 fileID = fopen('app_text_file.txt','w')            
                
                fprintf(fileID, 'omega_link1 = %d \n', omega_link1);
                fprintf(fileID, 'omega_link2 = %d \n', omega_link2);
                fprintf(fileID, 'omega_link3 = %d \n', omega_link3);
                fprintf(fileID, 'omega_link4 = %d \n', omega_link4);
                fprintf(fileID, 'omega_link5 = %d \n', omega_link5);
                
                fprintf(fileID, 'zeta_link1 = %d \n', zeta_link1);
                fprintf(fileID, 'zeta_link2 = %d \n', zeta_link2);
                fprintf(fileID, 'zeta_link3 = %d \n', zeta_link3);
                fprintf(fileID, 'zeta_link4 = %d \n', zeta_link4);
                fprintf(fileID, 'zeta_link5 = %d \n', zeta_link5);
                
                fprintf(fileID, 'K_LQR1_0 = %d \n', K_LQR1(1));
                fprintf(fileID, 'K_LQR1_1 = %d \n', K_LQR1(2));
                
                fprintf(fileID, 'K_LQR2_0 = %d \n', K_LQR2(1));
                fprintf(fileID, 'K_LQR2_1 = %d \n', K_LQR2(2));
                
                fprintf(fileID, 'K_LQR3_0 = %d \n', K_LQR3(1));
                fprintf(fileID, 'K_LQR3_1 = %d \n', K_LQR3(2));
                
                fprintf(fileID, 'K_LQR4_0 = %d \n', K_LQR4(1));
                fprintf(fileID, 'K_LQR4_1 = %d \n', K_LQR4(2));
                
                fprintf(fileID, 'K_LQR5_0 = %d \n', K_LQR5(1));
                fprintf(fileID, 'K_LQR5_1 = %d \n', K_LQR5(2));
                
                fprintf(fileID, 'alpha = %d \n', app.alphaEditField.Value);
                
                fprintf(fileID, 'p_x = %d \n', app.p_xEditField.Value);
                fprintf(fileID, 'p_y = %d \n', app.p_yEditField.Value);
                fprintf(fileID, 'p_z = %d \n', app.p_zEditField.Value);
                
                fprintf(fileID, 'z_5x = %d \n', app.z_5xEditField.Value);
                fprintf(fileID, 'z_5y = %d \n', app.z_5yEditField.Value);
                fprintf(fileID, 'z_5z = %d \n', app.z_5zEditField.Value);
                
                fprintf(fileID, 'trajectory planning = %d \n', app.TrajectoryplanningEditField.Value);
                fprintf(fileID, 'T_max = %d \n', app.T_maxEditField.Value);
                
                fclose(fileID);

%                 
            else
                app.connect.Text = 'Connect';
                [status,pID] = system('pidof /usr/bin/python /opt/ros/melodic/bin/rosmaster');
                kill_command = 'kill ' + string(pID);
                status = system(kill_command);
            end
            
%             
            
           
        end

        % Button pushed function: WaistPositionButton
        function WaistPositionButtonPushed(app, event)
            A = csvread('~/interbotix_ws/src/Interbotix_src/Gazebo/Data_log/data.csv');
            figure(1);
            plot(A(:,1),A(:,2));
            hold on;
            plot(A(:,1),A(:,3));
            grid minor;
            title('Waist Position');
            legend('Referance position', 'Current Position');
            xlabel('Time(sec)');
            ylabel('Radians');
        end

        % Button pushed function: ShoulderPositionButton
        function ShoulderPositionButtonPushed(app, event)
            A = csvread('~/interbotix_ws/src/Interbotix_src/Gazebo/Data_log/data.csv');
            figure(2);
            plot(A(:,1),A(:,4));
            hold on;
            plot(A(:,1),A(:,5));
            grid minor;
            title('Shoulder Position');
            legend('Referance position', 'Current Position');
            xlabel('Time(sec)');
            ylabel('Radians');
        end

        % Button pushed function: ElbowPositionButton
        function ElbowPositionButtonPushed(app, event)
            A = csvread('~/interbotix_ws/src/Interbotix_src/Gazebo/Data_log/data.csv');
            figure(3);
            plot(A(:,1),A(:,6));
            hold on;
            plot(A(:,1),A(:,7));
            grid minor;
            title('Elbow Position');
            legend('Referance position', 'Current Position');
            xlabel('Time(sec)');
            ylabel('Radians');
        end

        % Button pushed function: WristPositionButton
        function WristPositionButtonPushed(app, event)
            A = csvread('~/interbotix_ws/src/Interbotix_src/Gazebo/Data_log/data.csv');
            figure(4);
            plot(A(:,1),A(:,8));
            hold on;
            plot(A(:,1),A(:,9));
            grid minor;
            title('Wrist Position');
            legend('Referance position', 'Current Position');
            xlabel('Time(sec)');
            ylabel('Radians');
        end

        % Button pushed function: WristRotateButton
        function WristRotateButtonPushed(app, event)
            A = csvread('~/interbotix_ws/src/Interbotix_src/Gazebo/Data_log/data.csv');
            figure(5);
            plot(A(:,1),A(:,10));
            hold on;
            plot(A(:,1),A(:,11));
            grid minor;
            title('Wrist Rotation');
            legend('Referance position', 'Current Position');
            xlabel('Time(sec)');
            ylabel('Radians');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.omega1EditFieldLabel = uilabel(app.UIFigure);
            app.omega1EditFieldLabel.Text = 'omega1';
            app.omega1EditField = uieditfield(app.UIFigure, 'numeric');
            app.omega1EditField.Value = 12;
            app.omega2EditFieldLabel = uilabel(app.UIFigure);
            app.omega2EditFieldLabel.Text = 'omega2';
            app.omega2EditField = uieditfield(app.UIFigure, 'numeric');
            app.omega2EditField.Value = 50;
            app.omega3EditFieldLabel = uilabel(app.UIFigure);
            app.omega3EditFieldLabel.Text = 'omega3';
            app.omega3EditField = uieditfield(app.UIFigure, 'numeric');
            app.omega3EditField.Value = 45;
            app.omega4EditFieldLabel = uilabel(app.UIFigure);
            app.omega4EditFieldLabel.Text = 'omega4';
            app.omega4EditField = uieditfield(app.UIFigure, 'numeric');
            app.omega4EditField.Value = 40;
            app.omega5EditFieldLabel = uilabel(app.UIFigure);
            app.omega5EditFieldLabel.Text = 'omega5';
            app.omega5EditField = uieditfield(app.UIFigure, 'numeric');
            app.omega5EditField.Value = 5;
            app.r2link1EditFieldLabel = uilabel(app.UIFigure);
            app.r2link1EditFieldLabel.Text = 'r2 link 1';
            app.r2link1EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2link1EditField.Value = 2;
            app.r2link2EditFieldLabel = uilabel(app.UIFigure);
            app.r2link2EditFieldLabel.Text = 'r2 link 2';
            app.r2link2EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2link2EditField.Value = 6;
            app.r2link3EditFieldLabel = uilabel(app.UIFigure);
            app.r2link3EditFieldLabel.Text = 'r2 link 3';
            app.r2link3EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2link3EditField.Value = 1;
            app.r2link4EditFieldLabel = uilabel(app.UIFigure);
            app.r2link4EditFieldLabel.Text = 'r2 link 4';
            app.r2link4EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2link4EditField.Value = 1;
            app.r2link5EditFieldLabel = uilabel(app.UIFigure);
            app.r2link5EditFieldLabel.Text = 'r2 link 5';
            app.r2link5EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2link5EditField.Value = 1;
            app.r22link1EditFieldLabel = uilabel(app.UIFigure);
            app.r22link1EditFieldLabel.Text = 'r22 link 1';
            app.r22link1EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22link1EditField.Value = 1;
            app.r22link2EditFieldLabel = uilabel(app.UIFigure);
            app.r22link2EditFieldLabel.Text = 'r22 link 2';
            app.r22link2EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22link2EditField.Value = 1;
            app.r22link3EditFieldLabel = uilabel(app.UIFigure);
            app.r22link3EditFieldLabel.Text = 'r22 link 3';
            app.r22link3EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22link3EditField.Value = 1;
            app.r22link4EditFieldLabel = uilabel(app.UIFigure);
            app.r22link4EditFieldLabel.Text = 'r22 link 4';
            app.r22link4EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22link4EditField.Value = 1;
            app.r22link5EditFieldLabel = uilabel(app.UIFigure);
            app.r22link5EditFieldLabel.Text = 'r22 link 5';
            app.r22link5EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22link5EditField.Value = 1;
            app.r11link1EditFieldLabel = uilabel(app.UIFigure);
            app.r11link1EditFieldLabel.Text = 'r11 link 1';
            app.r11link1EditField = uieditfield(app.UIFigure, 'numeric');
            app.r11link1EditField.Value = 1;
            app.r11link2EditFieldLabel = uilabel(app.UIFigure);
            app.r11link2EditFieldLabel.Text = 'r11 link 2';
            app.r11link2EditField = uieditfield(app.UIFigure, 'numeric');
            app.r11link2EditField.Value = 10000000;
            app.r11link3EditFieldLabel = uilabel(app.UIFigure);
            app.r11link3EditFieldLabel.Text = 'r11 link 3';
            app.r11link3EditField = uieditfield(app.UIFigure, 'numeric');
            app.r11link3EditField.Value = 40000;
            app.r11link4EditFieldLabel = uilabel(app.UIFigure);
            app.r11link4EditFieldLabel.Text = 'r11 link 4';
            app.r11link4EditField = uieditfield(app.UIFigure, 'numeric');
            app.r11link4EditField.Value = 50000;
            app.r11link5EditFieldLabel = uilabel(app.UIFigure);
            app.r11link5EditFieldLabel.Text = 'r11 link 5';
            app.r11link5EditField = uieditfield(app.UIFigure, 'numeric');
            app.r11link5EditField.Value = 100;
            app.zeta1EditFieldLabel = uilabel(app.UIFigure);
            app.zeta1EditFieldLabel.Text = 'zeta1';
            app.zeta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta1EditField.Value = 0.1;
            app.zeta2EditFieldLabel = uilabel(app.UIFigure);
            app.zeta2EditFieldLabel.Text = 'zeta2';
            app.zeta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta2EditField.Value = 0.1;
            app.zeta3EditFieldLabel = uilabel(app.UIFigure);
            app.zeta3EditFieldLabel.Text = 'zeta3';
            app.zeta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta3EditField.Value = 0.1;
            app.zeta4EditFieldLabel = uilabel(app.UIFigure);
            app.zeta4EditFieldLabel.Text = 'zeta4';
            app.zeta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta4EditField.Value = 0.1;
            app.zeta5EditFieldLabel = uilabel(app.UIFigure);
            app.zeta5EditFieldLabel.Text = 'zeta5';
            app.zeta5EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta5EditField.Value = 0.1;
            app.T_maxEditFieldLabel = uilabel(app.UIFigure);
            app.T_maxEditFieldLabel.Text = 'T_max';
            app.T_maxEditField = uieditfield(app.UIFigure, 'numeric');
            app.T_maxEditField.Value = 3;
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrajectoryplanningEditFieldLabel = uilabel(app.UIFigure);
            app.TrajectoryplanningEditFieldLabel.Text = 'Trajectory planning';
            app.TrajectoryplanningEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_5xEditFieldLabel = uilabel(app.UIFigure);
            app.z_5xEditFieldLabel.Text = 'z_5x';
            app.z_5xEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_5xEditField.Value = 1;
            app.z_5yEditFieldLabel = uilabel(app.UIFigure);
            app.z_5yEditFieldLabel.Text = 'z_5y';
            app.z_5yEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_5zEditFieldLabel = uilabel(app.UIFigure);
            app.z_5zEditFieldLabel.Text = 'z_5z';
            app.z_5zEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_xEditFieldLabel = uilabel(app.UIFigure);
            app.p_xEditFieldLabel.Text = 'p_x';
            app.p_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_xEditField.Value = 0.37;
            app.p_yEditFieldLabel = uilabel(app.UIFigure);
            app.p_yEditFieldLabel.Text = 'p_y';
            app.p_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_zEditFieldLabel = uilabel(app.UIFigure);
            app.p_zEditFieldLabel.Text = 'p_z';
            app.p_zEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_zEditField.Value = 0.32;
            app.UpdateParametersButton = uibutton(app.UIFigure, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = 'Update Parameters';
            app.connect = uibutton(app.UIFigure, 'push');
            app.connect.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.connect.Text = 'Connect';
            app.GraphsLabel = uilabel(app.UIFigure);
            app.GraphsLabel.Text = 'Graphs';
            app.WaistPositionButton = uibutton(app.UIFigure, 'push');
            app.WaistPositionButton.ButtonPushedFcn = createCallbackFcn(app, @WaistPositionButtonPushed, true);
            app.WaistPositionButton.Text = 'Waist Position';
            app.ElbowPositionButton = uibutton(app.UIFigure, 'push');
            app.ElbowPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ElbowPositionButtonPushed, true);
            app.ElbowPositionButton.Text = 'Elbow Position';
            app.WristRotateButton = uibutton(app.UIFigure, 'push');
            app.WristRotateButton.ButtonPushedFcn = createCallbackFcn(app, @WristRotateButtonPushed, true);
            app.WristRotateButton.Text = 'Wrist Rotate';
            app.ShoulderPositionButton = uibutton(app.UIFigure, 'push');
            app.ShoulderPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ShoulderPositionButtonPushed, true);
            app.ShoulderPositionButton.Text = 'Shoulder Position';
            app.WristPositionButton = uibutton(app.UIFigure, 'push');
            app.WristPositionButton.ButtonPushedFcn = createCallbackFcn(app, @WristPositionButtonPushed, true);
            app.WristPositionButton.Text = 'Wrist Position';
            app.GazeboRoboticArmSimulationLabel = uilabel(app.UIFigure);
            app.GazeboRoboticArmSimulationLabel.Text = 'Gazebo Robotic Arm Simulation';
            app.LSPB1quinticLabel = uilabel(app.UIFigure);
            app.LSPB1quinticLabel.Text = '{0 - LSPB & 1-quintic)';
            app.GripperRotationLabel = uilabel(app.UIFigure);
            app.GripperRotationLabel.Text = '(Gripper Rotation)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gazebo_arm_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end