classdef real_arm_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        r2_link1EditFieldLabel         matlab.ui.control.Label
        r2_link1EditField              matlab.ui.control.NumericEditField
        r2_link2EditFieldLabel         matlab.ui.control.Label
        r2_link2EditField              matlab.ui.control.NumericEditField
        r2_link3EditFieldLabel         matlab.ui.control.Label
        r2_link3EditField              matlab.ui.control.NumericEditField
        r2_link4EditFieldLabel         matlab.ui.control.Label
        r2_link4EditField              matlab.ui.control.NumericEditField
        Ki1EditFieldLabel              matlab.ui.control.Label
        Ki1EditField                   matlab.ui.control.NumericEditField
        Ki2Label                       matlab.ui.control.Label
        Ki2EditField                   matlab.ui.control.NumericEditField
        Ki3Label                       matlab.ui.control.Label
        Ki3EditField                   matlab.ui.control.NumericEditField
        Ki4Label                       matlab.ui.control.Label
        Ki4EditField                   matlab.ui.control.NumericEditField
        zeta1EditFieldLabel            matlab.ui.control.Label
        zeta1EditField                 matlab.ui.control.NumericEditField
        zeta2EditFieldLabel            matlab.ui.control.Label
        zeta2EditField                 matlab.ui.control.NumericEditField
        zeta3EditFieldLabel            matlab.ui.control.Label
        zeta3EditField                 matlab.ui.control.NumericEditField
        zeta4EditFieldLabel            matlab.ui.control.Label
        zeta4EditField                 matlab.ui.control.NumericEditField
        r2_link5EditFieldLabel         matlab.ui.control.Label
        r2_link5EditField              matlab.ui.control.NumericEditField
        Ki5EditFieldLabel              matlab.ui.control.Label
        Ki5EditField                   matlab.ui.control.NumericEditField
        zeta5EditFieldLabel            matlab.ui.control.Label
        zeta5EditField                 matlab.ui.control.NumericEditField
        omega1EditFieldLabel           matlab.ui.control.Label
        omega1EditField                matlab.ui.control.NumericEditField
        omega2EditFieldLabel           matlab.ui.control.Label
        omega2EditField                matlab.ui.control.NumericEditField
        omega3EditFieldLabel           matlab.ui.control.Label
        omega3EditField                matlab.ui.control.NumericEditField
        omega4EditFieldLabel           matlab.ui.control.Label
        omega4EditField                matlab.ui.control.NumericEditField
        omega5EditFieldLabel           matlab.ui.control.Label
        omega5EditField                matlab.ui.control.NumericEditField
        r22_link1EditFieldLabel        matlab.ui.control.Label
        r22_link1EditField             matlab.ui.control.NumericEditField
        r22_link2EditFieldLabel        matlab.ui.control.Label
        r22_link2EditField             matlab.ui.control.NumericEditField
        r22_link3EditFieldLabel        matlab.ui.control.Label
        r22_link3EditField             matlab.ui.control.NumericEditField
        r22_link4EditFieldLabel        matlab.ui.control.Label
        r22_link4EditField             matlab.ui.control.NumericEditField
        r22_link5EditFieldLabel        matlab.ui.control.Label
        r22_link5EditField             matlab.ui.control.NumericEditField
        r11_link1Label                 matlab.ui.control.Label
        r11_link1EditField             matlab.ui.control.NumericEditField
        r11_link2EditFieldLabel        matlab.ui.control.Label
        r11_link2EditField             matlab.ui.control.NumericEditField
        r11_link3EditFieldLabel        matlab.ui.control.Label
        r11_link3EditField             matlab.ui.control.NumericEditField
        r11_link4EditFieldLabel        matlab.ui.control.Label
        r11_link4EditField             matlab.ui.control.NumericEditField
        r11_link5EditFieldLabel        matlab.ui.control.Label
        r11_link5EditField             matlab.ui.control.NumericEditField
        UpdateParametersButton         matlab.ui.control.Button
        SendPoseButton                 matlab.ui.control.Button
        z_5xEditFieldLabel             matlab.ui.control.Label
        z_5xEditField                  matlab.ui.control.NumericEditField
        z_5yEditFieldLabel             matlab.ui.control.Label
        z_5yEditField                  matlab.ui.control.NumericEditField
        z_5zEditFieldLabel             matlab.ui.control.Label
        z_5zEditField                  matlab.ui.control.NumericEditField
        p_xEditFieldLabel              matlab.ui.control.Label
        p_xEditField                   matlab.ui.control.NumericEditField
        p_yEditFieldLabel              matlab.ui.control.Label
        p_yEditField                   matlab.ui.control.NumericEditField
        p_zEditFieldLabel              matlab.ui.control.Label
        p_zEditField                   matlab.ui.control.NumericEditField
        alphaEditFieldLabel            matlab.ui.control.Label
        alphaEditField                 matlab.ui.control.NumericEditField
        InverseKinematicsLabel         matlab.ui.control.Label
        WaistpositionButton            matlab.ui.control.Button
        RealRoboticArmSimulationLabel  matlab.ui.control.Label
        PlotsLabel                     matlab.ui.control.Label
        ElbowpositionButton            matlab.ui.control.Button
        ShoulderpositionButton         matlab.ui.control.Button
        WristpositionButton            matlab.ui.control.Button
        TorqueOffButton                matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
            omega1 = app.omega1EditField.Value;
            omega2 = app.omega2EditField.Value;
            omega3 = app.omega3EditField.Value;
            omega4 = app.omega4EditField.Value;
            omega5 = app.omega5EditField.Value;
            
            zeta1 = app.zeta1EditField.Value;
            zeta2 = app.zeta2EditField.Value;
            zeta3 = app.zeta3EditField.Value;
            zeta4 = app.zeta4EditField.Value;
            zeta5 = app.zeta5EditField.Value;
            
            Ki_1 = app.Ki1EditField.Value;
            Ki_2 = app.Ki2EditField.Value;
            Ki_3 = app.Ki3EditField.Value;
            Ki_4 = app.Ki4EditField.Value;
            Ki_5 = app.Ki5EditField.Value;
            
            r11_link1 = app.r11_link1EditField.Value;
            r11_link2 = app.r11_link2EditField.Value;
            r11_link3 = app.r11_link3EditField.Value;
            r11_link4 = app.r11_link4EditField.Value;
            r11_link5 = app.r11_link5EditField.Value;
            
            r22_link1 = app.r22_link1EditField.Value;
            r22_link2 = app.r22_link2EditField.Value;
            r22_link3 = app.r22_link3EditField.Value;
            r22_link4 = app.r22_link4EditField.Value;
            r22_link5 = app.r22_link5EditField.Value;
            
            r2_link1 = app.r2_link1EditField.Value;
            r2_link2 = app.r2_link2EditField.Value;
            r2_link3 = app.r2_link3EditField.Value;
            r2_link4 = app.r2_link4EditField.Value;
            r2_link5 = app.r2_link5EditField.Value;
            
            p_x = double(app.p_xEditField.Value);
            p_y = double(app.p_yEditField.Value);
            p_z = double(app.p_zEditField.Value);
            
            z_5x = double(app.z_5xEditField.Value);
            z_5y = double(app.z_5yEditField.Value);
            z_5z = double(app.z_5zEditField.Value);
            z_5 = [z_5x;z_5y;z_5z];
            
            
            d1 = 0.11325;
            a2 = 0.20616;
            a3 = 0.2;
            d5 = 0.10915+0.065; 
            alpha = app.alphaEditField.Value;
            
            
%             theta_5 = alpha;
%             theta_1 = atan(p_y/p_x);
%             z5X = double(sqrt(z_5(1,1)^2 + z_5(2,1)^2));
%             phi = atan(z_5(3,1)/z5X);
%             p_X = double(sqrt(p_x^2 + p_y^2));
%             
%             rho_X = p_X - d5*cos(phi);
%             rho_z = p_z - d1-d5*sin(phi);
%             
%             C = double((rho_X^2 + rho_z^2 + a2^2 - a3^2)/(2*a2));
%             
%             theta_2 = 2*atan((rho_z + double(sqrt(rho_z^2 + rho_X^2 - C^2)))/(rho_X + C));
%             theta_3 = atan((rho_z - a2*sin(theta_2))/(rho_X - a2*cos(theta_2))) - theta_2;
%             theta_4 = phi - theta_3 - theta_2;
            

            theta_5 = alpha;
            theta_1 = atan2(p_y,p_x);
            z5X = double(sqrt(z_5(1,1)^2 + z_5(2,1)^2));
            phi = atan2(z_5(3,1),z5X);
            p_X = double(sqrt(p_x^2 + p_y^2));
            
            rho_X = p_X - d5*cos(phi);
            rho_z = p_z - d1-d5*sin(phi);
            
            C = double((rho_X^2 + rho_z^2 + a2^2 - a3^2)/(2*a2));
            
            theta_2 = 2*atan2((rho_z + double(sqrt(rho_z^2 + rho_X^2 - C^2))),(rho_X + C));
            theta_3 = atan2((rho_z - a2*sin(theta_2)),(rho_X - a2*cos(theta_2))) - theta_2;
            theta_4 = phi - theta_3 - theta_2;
            
            
            theta_2 = -1*theta_2 + (pi/2);
            theta_3 = theta_3 +pi/2 ;   
            
            
            omega = [omega1 omega2 omega3 omega4 omega5]';
            zeta = [zeta1 zeta2 zeta3 zeta4 zeta5]';
            Ki = [Ki_1 Ki_2 Ki_3 Ki_4 Ki_5]';
            r11 = [r11_link1 r11_link2 r11_link3 r11_link4 r11_link5]';
            r22 = [r22_link1 r22_link2 r22_link3 r22_link4 r22_link5]';
            r2 = [r2_link1 r2_link2 r2_link3 r2_link4 r2_link5]';
            theta = [theta_1, theta_2, theta_3, theta_4, theta_5]';
            
            Table_data = table(omega,zeta,Ki,r11,r22,r2, theta);
            file_name = '~/interbotix_ws/src/Interbotix_src/Actual_robot/real_robotic_arm_parameters.xlsx';
            writetable(Table_data,file_name);
            
        end

        % Button pushed function: SendPoseButton
        function SendPoseButtonPushed(app, event)
            addpath(genpath('~/interbotix_ws/src/Interbotix_src/Actual_robot'));
            control_new_Aug12_edit
        end

        % Button pushed function: WaistpositionButton
        function WaistpositionButtonPushed(app, event)
            p = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/p_data.xlsx');
            qref_plot = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/qref_plot_data.xlsx');
            plot_q = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/plot_q_data.xlsx');
            
            figure
            plot(p,qref_plot(1,:),p,plot_q(1,:),'LineWidth',2)
            
            grid on
            title('Waist position');
            xlabel('seconds');
            ylabel('angle in radians');
            legend('reference', 'actual');
        end

        % Button pushed function: ShoulderpositionButton
        function ShoulderpositionButtonPushed(app, event)
            p = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/p_data.xlsx');
            qref_plot = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/qref_plot_data.xlsx');
            plot_q = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/plot_q_data.xlsx');
            
            figure
            plot(p,qref_plot(2,:),p,plot_q(2,:),'LineWidth',2)
            
            grid on
            title('Shoulder position');
            xlabel('seconds');
            ylabel('angle in radians');
            legend('reference', 'actual');
        end

        % Button pushed function: ElbowpositionButton
        function ElbowpositionButtonPushed(app, event)
            p = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/p_data.xlsx');
            qref_plot = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/qref_plot_data.xlsx');
            plot_q = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/plot_q_data.xlsx');
            
            figure
            plot(p,qref_plot(3,:),p,plot_q(3,:),'LineWidth',2)
            
            grid on
            title('Elbow position');
            xlabel('seconds');
            ylabel('angle in radians');
            legend('reference', 'actual');
        end

        % Button pushed function: WristpositionButton
        function WristpositionButtonPushed(app, event)
            p = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/p_data.xlsx');
            qref_plot = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/qref_plot_data.xlsx');
            plot_q = xlsread('~/interbotix_ws/src/Interbotix_src/Actual_robot/plot_q_data.xlsx');
            
            figure
            plot(p,qref_plot(4,:),p,plot_q(4,:),'LineWidth',2)
            
            grid on
            title('Wrist position');
            xlabel('seconds');
            ylabel('angle in radians');
            legend('reference', 'actual');
        end

        % Button pushed function: TorqueOffButton
        function TorqueOffButtonPushed(app, event)
                addpath(genpath('~/DynamixelSDK-3.7.21'));
              
                lib_name = '';
                
                if strcmp(computer, 'PCWIN')
                  lib_name = 'dxl_x86_c';
                elseif strcmp(computer, 'PCWIN64')
                  lib_name = 'dxl_x64_c';
                elseif strcmp(computer, 'GLNX86')
                  lib_name = 'libdxl_x86_c';
                elseif strcmp(computer, 'GLNXA64')
                  lib_name = 'libdxl_x64_c';
                elseif strcmp(computer, 'MACI64')
                  lib_name = 'libdxl_mac_c';
                end
                
                % Load Libraries
                if ~libisloaded(lib_name)
                    [notfound, warnings] = loadlibrary(lib_name, 'dynamixel_sdk.h', 'addheader', 'port_handler.h', 'addheader', 'packet_handler.h', 'addheader', 'group_sync_write.h', 'addheader', 'group_sync_read.h');
                end

              BAUDRATE = 1000000;
              DEVICENAME = '/dev/ttyUSB0';       % Check which port is being used on your controller
              port_num = portHandler(DEVICENAME);
              
              packetHandler();
              
                          % Open port
            if (openPort(port_num))
                fprintf('Succeeded to open the port!\n');
            else
                unloadlibrary(lib_name);
                fprintf('Failed to open the port!\n');
                input('Press any key to terminate...\n');
                return;
            end
            
            
            % Set port baudrate
            if (setBaudRate(port_num, BAUDRATE))
                fprintf('Succeeded to change the baudrate!\n');
            else
                unloadlibrary(lib_name);
                fprintf('Failed to change the baudrate!\n');
                input('Press any key to terminate...\n');
                return;
            end

              
              write1ByteTxRx(port_num, 2.0, 1, 64, 0);
              write1ByteTxRx(port_num, 2.0, 5, 64, 0);
              write1ByteTxRx(port_num, 2.0, 4, 64, 0);
              write1ByteTxRx(port_num, 2.0, 2, 64, 0);
                          
                          %%
            %Disconnect
             closePort(port_num);
            
            % Unload Library
            unloadlibrary(lib_name);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.r2_link1EditFieldLabel = uilabel(app.UIFigure);
            app.r2_link1EditFieldLabel.Text = 'r2_link1';
            app.r2_link1EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2_link1EditField.Value = 3;
            app.r2_link2EditFieldLabel = uilabel(app.UIFigure);
            app.r2_link2EditFieldLabel.Text = 'r2_link2';
            app.r2_link2EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2_link2EditField.Value = 800;
            app.r2_link3EditFieldLabel = uilabel(app.UIFigure);
            app.r2_link3EditFieldLabel.Text = 'r2_link3';
            app.r2_link3EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2_link3EditField.Value = 100;
            app.r2_link4EditFieldLabel = uilabel(app.UIFigure);
            app.r2_link4EditFieldLabel.Text = 'r2_link4';
            app.r2_link4EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2_link4EditField.Value = 3;
            app.Ki1EditFieldLabel = uilabel(app.UIFigure);
            app.Ki1EditFieldLabel.Text = 'Ki 1';
            app.Ki1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Ki2Label = uilabel(app.UIFigure);
            app.Ki2Label.Text = 'Ki 2';
            app.Ki2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Ki2EditField.Value = 1;
            app.Ki3Label = uilabel(app.UIFigure);
            app.Ki3Label.Text = 'Ki 3';
            app.Ki3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Ki3EditField.Value = 1;
            app.Ki4Label = uilabel(app.UIFigure);
            app.Ki4Label.Text = 'Ki 4';
            app.Ki4EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta1EditFieldLabel = uilabel(app.UIFigure);
            app.zeta1EditFieldLabel.Text = 'zeta 1';
            app.zeta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta1EditField.Value = 0.1;
            app.zeta2EditFieldLabel = uilabel(app.UIFigure);
            app.zeta2EditFieldLabel.Text = 'zeta 2';
            app.zeta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta2EditField.Value = 0.1;
            app.zeta3EditFieldLabel = uilabel(app.UIFigure);
            app.zeta3EditFieldLabel.Text = 'zeta 3';
            app.zeta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta4EditFieldLabel = uilabel(app.UIFigure);
            app.zeta4EditFieldLabel.Text = 'zeta 4';
            app.zeta4EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta4EditField.Value = 0.1;
            app.r2_link5EditFieldLabel = uilabel(app.UIFigure);
            app.r2_link5EditFieldLabel.Text = 'r2_link5';
            app.r2_link5EditField = uieditfield(app.UIFigure, 'numeric');
            app.r2_link5EditField.Value = 1;
            app.Ki5EditFieldLabel = uilabel(app.UIFigure);
            app.Ki5EditFieldLabel.Text = ' Ki 5';
            app.Ki5EditField = uieditfield(app.UIFigure, 'numeric');
            app.zeta5EditFieldLabel = uilabel(app.UIFigure);
            app.zeta5EditFieldLabel.Text = 'zeta 5';
            app.zeta5EditField = uieditfield(app.UIFigure, 'numeric');
            app.omega1EditFieldLabel = uilabel(app.UIFigure);
            app.omega1EditFieldLabel.Text = 'omega 1';
            app.omega1EditField = uieditfield(app.UIFigure, 'numeric');
            app.omega1EditField.Value = 11;
            app.omega2EditFieldLabel = uilabel(app.UIFigure);
            app.omega2EditFieldLabel.Text = 'omega 2';
            app.omega2EditField = uieditfield(app.UIFigure, 'numeric');
            app.omega2EditField.Value = 11;
            app.omega3EditFieldLabel = uilabel(app.UIFigure);
            app.omega3EditFieldLabel.Text = 'omega 3';
            app.omega3EditField = uieditfield(app.UIFigure, 'numeric');
            app.omega3EditField.Value = 11;
            app.omega4EditFieldLabel = uilabel(app.UIFigure);
            app.omega4EditFieldLabel.Text = 'omega 4';
            app.omega4EditField = uieditfield(app.UIFigure, 'numeric');
            app.omega4EditField.Value = 8;
            app.omega5EditFieldLabel = uilabel(app.UIFigure);
            app.omega5EditFieldLabel.Text = 'omega 5';
            app.omega5EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_link1EditFieldLabel = uilabel(app.UIFigure);
            app.r22_link1EditFieldLabel.Text = 'r22_link1';
            app.r22_link1EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_link1EditField.Value = 10;
            app.r22_link2EditFieldLabel = uilabel(app.UIFigure);
            app.r22_link2EditFieldLabel.Text = 'r22_link2';
            app.r22_link2EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_link2EditField.Value = 70;
            app.r22_link3EditFieldLabel = uilabel(app.UIFigure);
            app.r22_link3EditFieldLabel.Text = 'r22_link3';
            app.r22_link3EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_link3EditField.Value = 800;
            app.r22_link4EditFieldLabel = uilabel(app.UIFigure);
            app.r22_link4EditFieldLabel.Text = 'r22_link 4';
            app.r22_link4EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_link4EditField.Value = 8000;
            app.r22_link5EditFieldLabel = uilabel(app.UIFigure);
            app.r22_link5EditFieldLabel.Text = 'r22_link5';
            app.r22_link5EditField = uieditfield(app.UIFigure, 'numeric');
            app.r22_link5EditField.Value = 1;
            app.r11_link1Label = uilabel(app.UIFigure);
            app.r11_link1Label.Text = 'r11_link1';
            app.r11_link1EditField = uieditfield(app.UIFigure, 'numeric');
            app.r11_link1EditField.Value = 10;
            app.r11_link2EditFieldLabel = uilabel(app.UIFigure);
            app.r11_link2EditFieldLabel.Text = 'r11_link2';
            app.r11_link2EditField = uieditfield(app.UIFigure, 'numeric');
            app.r11_link2EditField.Value = 70;
            app.r11_link3EditFieldLabel = uilabel(app.UIFigure);
            app.r11_link3EditFieldLabel.Text = 'r11_link3';
            app.r11_link3EditField = uieditfield(app.UIFigure, 'numeric');
            app.r11_link3EditField.Value = 800;
            app.r11_link4EditFieldLabel = uilabel(app.UIFigure);
            app.r11_link4EditFieldLabel.Text = 'r11_link4';
            app.r11_link4EditField = uieditfield(app.UIFigure, 'numeric');
            app.r11_link4EditField.Value = 50000;
            app.r11_link5EditFieldLabel = uilabel(app.UIFigure);
            app.r11_link5EditFieldLabel.Text = 'r11_link5';
            app.r11_link5EditField = uieditfield(app.UIFigure, 'numeric');
            app.r11_link5EditField.Value = 1;
            app.UpdateParametersButton = uibutton(app.UIFigure, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Text = 'Update Parameters';
            app.SendPoseButton = uibutton(app.UIFigure, 'push');
            app.SendPoseButton.ButtonPushedFcn = createCallbackFcn(app, @SendPoseButtonPushed, true);
            app.SendPoseButton.Text = 'Send Pose';
            app.z_5xEditFieldLabel = uilabel(app.UIFigure);
            app.z_5xEditFieldLabel.Text = 'z_5x';
            app.z_5xEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_5xEditField.Value = 1;
            app.z_5yEditFieldLabel = uilabel(app.UIFigure);
            app.z_5yEditFieldLabel.Text = 'z_5y';
            app.z_5yEditField = uieditfield(app.UIFigure, 'numeric');
            app.z_5zEditFieldLabel = uilabel(app.UIFigure);
            app.z_5zEditFieldLabel.Text = 'z_5z';
            app.z_5zEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_xEditFieldLabel = uilabel(app.UIFigure);
            app.p_xEditFieldLabel.Text = 'p_x';
            app.p_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_xEditField.Value = 0.19;
            app.p_yEditFieldLabel = uilabel(app.UIFigure);
            app.p_yEditFieldLabel.Text = 'p_y';
            app.p_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_yEditField.Value = 0.17;
            app.p_zEditFieldLabel = uilabel(app.UIFigure);
            app.p_zEditFieldLabel.Text = 'p_z';
            app.p_zEditField = uieditfield(app.UIFigure, 'numeric');
            app.p_zEditField.Value = 0.25;
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.InverseKinematicsLabel = uilabel(app.UIFigure);
            app.InverseKinematicsLabel.Text = 'Inverse Kinematics:';
            app.WaistpositionButton = uibutton(app.UIFigure, 'push');
            app.WaistpositionButton.ButtonPushedFcn = createCallbackFcn(app, @WaistpositionButtonPushed, true);
            app.WaistpositionButton.Text = 'Waist position';
            app.RealRoboticArmSimulationLabel = uilabel(app.UIFigure);
            app.RealRoboticArmSimulationLabel.Text = 'Real Robotic Arm Simulation';
            app.PlotsLabel = uilabel(app.UIFigure);
            app.PlotsLabel.Text = 'Plots:';
            app.ElbowpositionButton = uibutton(app.UIFigure, 'push');
            app.ElbowpositionButton.ButtonPushedFcn = createCallbackFcn(app, @ElbowpositionButtonPushed, true);
            app.ElbowpositionButton.Text = 'Elbow position';
            app.ShoulderpositionButton = uibutton(app.UIFigure, 'push');
            app.ShoulderpositionButton.ButtonPushedFcn = createCallbackFcn(app, @ShoulderpositionButtonPushed, true);
            app.ShoulderpositionButton.Text = 'Shoulder position';
            app.WristpositionButton = uibutton(app.UIFigure, 'push');
            app.WristpositionButton.ButtonPushedFcn = createCallbackFcn(app, @WristpositionButtonPushed, true);
            app.WristpositionButton.Text = 'Wrist position';
            app.TorqueOffButton = uibutton(app.UIFigure, 'push');
            app.TorqueOffButton.ButtonPushedFcn = createCallbackFcn(app, @TorqueOffButtonPushed, true);
            app.TorqueOffButton.Text = 'Torque Off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = real_arm_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end